classdef ShiftLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DisplayUIFigure          matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        Panel2                   matlab.ui.container.Panel
        StrandCheckBox           matlab.ui.control.CheckBox
        HelixCheckBox            matlab.ui.control.CheckBox
        LoopCheckBox             matlab.ui.control.CheckBox
        Panel2_2                 matlab.ui.container.Panel
        C_betaCheckBox           matlab.ui.control.CheckBox
        C_alphaCheckBox          matlab.ui.control.CheckBox
        C_gammaCheckBox          matlab.ui.control.CheckBox
        C_deltaCheckBox          matlab.ui.control.CheckBox
        HCheckBox                matlab.ui.control.CheckBox
        NCheckBox                matlab.ui.control.CheckBox
        Panel2_4                 matlab.ui.container.Panel
        AminoacidDropDownLabel   matlab.ui.control.Label
        AminoacidDropDown        matlab.ui.control.DropDown
        AminoacidSelectionLabel  matlab.ui.control.Label
        LoadDataButton           matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        PlotTab                  matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        ButtonGroup              matlab.ui.container.ButtonGroup
        ErrorbarButton           matlab.ui.control.RadioButton
        HistogramButton          matlab.ui.control.RadioButton
        ChemicalshiftdistributionsSwitchLabel  matlab.ui.control.Label
        ChemicalshiftdistributionsSwitch  matlab.ui.control.Switch
        DataTab                  matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the Display class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Weight';
            app.UIAxes.YLabel.String = app.ChemicalshiftdistributionsSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.ChemicalshiftdistributionsSwitch.Value;
            app.UIAxes.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.C_betaCheckBox.Value && ~app.C_alphaCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.C_alphaCheckBox.Value && ~app.C_betaCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.C_alphaCheckBox.Value && ~app.C_betaCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.HelixCheckBox.Value && ~app.StrandCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.StrandCheckBox.Value && ~app.HelixCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.StrandCheckBox.Value && ~app.HelixCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.AminoacidDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.AminoacidDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load('patients.mat','LastName','Gender','Smoker','Age','Height','Weight','Diastolic','Systolic','Location');
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            app.UITable.Data = app.Data;
            app.BinWidth = app.BinWidthSlider.Value;
            
            % Update the axes with the corresponding data.
            updateSelectedGenders(app)
            refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Callback function: ButtonGroup, 
        % ChemicalshiftdistributionsSwitch, UITable
        function refreshplot(app, event)
            Genders = app.SelectedGenders;
            Colors = app.SelectedColors;
            
            % Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            app.Histogram = gobjects(0);
            
            % Select relevant segment of data
            xdata = app.Data.Weight;
            ydata = app.Data.(app.ChemicalshiftdistributionsSwitch.Value);
            
            % Filter the data according to the controls
            filterData(app);
            
            % Create either a scatter plot or histogram, based on selection
            switch app.ButtonGroup.SelectedObject.Text
                
                case 'Errorbar'
                    % Build an errorbar plot for exploiting chemical shift
                    % correlations
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
                    end
                    annotateScatterPlot(app)
                    
                case 'Histogram'
                    % Build a histogram for each selected gender
                    for ii = 1:length(Genders)
                        selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
                        NBins = numhistbins(app,ydata(selectedpatients));
                        h = histogram(app.UIAxes,ydata(selectedpatients),NBins,'BinLimits',[floor(min(ydata)) ceil(max(ydata))]);
                        h.EdgeColor = Colors{ii};
                        h.FaceColor = Colors{ii};
                        app.Histogram = [app.Histogram h];
                    end
                    annotateHistogram(app)
                    
            end
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Value changed function: AminoacidDropDown, 
        % C_alphaCheckBox, C_betaCheckBox, HelixCheckBox, 
        % StrandCheckBox
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            Genders = [];
            Colors = [];
            Smoker = [];
            
            if app.HelixCheckBox.Value
                Genders = "Male";
                Colors = "blue";
            end
            if app.StrandCheckBox.Value
                Genders = [Genders "Female"];
                Colors = [Colors "red"];
            end
            if app.C_alphaCheckBox.Value
                Smoker = "Yes";
            end
            if app.C_betaCheckBox.Value
                Smoker = [Smoker "No"];
            end
            
            if isempty(Genders) || isempty(Smoker)
                % Disable the switches and buttons if they were on
                app.ChemicalshiftdistributionsSwitch.Enable = 'off';
                app.ErrorbarButton.Enable = 'off';
                app.HistogramButton.Enable = 'off';
                app.BinWidthSliderLabel.Enable = 'off';
                app.BinWidthSlider.Enable = 'off';
            else
                % Enable the switches and buttons if they were off
                app.ChemicalshiftdistributionsSwitch.Enable = 'on';
                app.ErrorbarButton.Enable = 'on';
                app.HistogramButton.Enable = 'on';
                app.BinWidthSliderLabel.Enable = 'on';
                app.BinWidthSlider.Enable = 'on';
            end
            app.SelectedGenders = Genders;
            app.SelectedColors = Colors;
            
            refreshplot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DisplayUIFigure = uifigure('Visible', 'off');
            app.DisplayUIFigure.Name = 'Patients Display';
            app.DisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'Secondary-structure shifts';
            app.StrandCheckBox = uicheckbox(app.Panel2);
            app.StrandCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.StrandCheckBox.Text = 'Strand';
            app.HelixCheckBox = uicheckbox(app.Panel2);
            app.HelixCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.HelixCheckBox.Text = 'Helix';
            app.HelixCheckBox.Value = true;
            app.LoopCheckBox = uicheckbox(app.Panel2);
            app.LoopCheckBox.Text = 'Loop';
            app.Panel2_2 = uipanel(app.LeftPanel);
            app.Panel2_2.Title = 'Nuclei';
            app.C_betaCheckBox = uicheckbox(app.Panel2_2);
            app.C_betaCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.C_betaCheckBox.Text = '13C_beta';
            app.C_alphaCheckBox = uicheckbox(app.Panel2_2);
            app.C_alphaCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.C_alphaCheckBox.Text = '13C_alpha';
            app.C_gammaCheckBox = uicheckbox(app.Panel2_2);
            app.C_gammaCheckBox.Text = '13C_gamma';
            app.C_deltaCheckBox = uicheckbox(app.Panel2_2);
            app.C_deltaCheckBox.Text = '13C_delta';
            app.HCheckBox = uicheckbox(app.Panel2_2);
            app.HCheckBox.Text = '1H';
            app.NCheckBox = uicheckbox(app.Panel2_2);
            app.NCheckBox.Text = '15N';
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Select the amino acid of interest';
            app.AminoacidDropDownLabel = uilabel(app.Panel2_4);
            app.AminoacidDropDownLabel.Text = 'Amino acid';
            app.AminoacidDropDown = uidropdown(app.Panel2_4);
            app.AminoacidDropDown.Items = {'Arg', 'Asn', 'Asp', 'Cys', 'Gln', 'Glu', 'Gly', 'His', 'Ile', 'Leu', 'Lys', 'Met', 'Phe', 'Pro', 'Ser', 'Thr', 'Trp', 'Tyr', 'Val', 'All', 'Ala'};
            app.AminoacidDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.AminoacidDropDown.Value = 'All';
            app.AminoacidSelectionLabel = uilabel(app.LeftPanel);
            app.AminoacidSelectionLabel.Text = 'Aminoacid Selection';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.Text = 'Load Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Weight')
            ylabel(app.UIAxes, 'Diastolic')
            app.ButtonGroup = uibuttongroup(app.PlotTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.ErrorbarButton = uiradiobutton(app.ButtonGroup);
            app.ErrorbarButton.Text = 'Errorbar';
            app.ErrorbarButton.Value = true;
            app.HistogramButton = uiradiobutton(app.ButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.ChemicalshiftdistributionsSwitchLabel = uilabel(app.PlotTab);
            app.ChemicalshiftdistributionsSwitchLabel.Text = 'Chemical shift distributions';
            app.ChemicalshiftdistributionsSwitch = uiswitch(app.PlotTab, 'slider');
            app.ChemicalshiftdistributionsSwitch.Items = {'1D', '2D'};
            app.ChemicalshiftdistributionsSwitch.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.ChemicalshiftdistributionsSwitch.Value = '1D';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
            % Show the figure after all components are created
            app.DisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShiftLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DisplayUIFigure)
        end
    end
end